classdef SimplePlotterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MarkerSelectButtonGroup  matlab.ui.container.ButtonGroup
        LeftButton               matlab.ui.control.ToggleButton
        NoneButton               matlab.ui.control.ToggleButton
        RightButton              matlab.ui.control.ToggleButton
        LeftMotorPanel           matlab.ui.container.Panel
        LeftMotorStateButton     matlab.ui.control.StateButton
        SpeedSliderLabel         matlab.ui.control.Label
        LeftMotorSpeedSlider     matlab.ui.control.Slider
        LeftMotorStateLabel      matlab.ui.control.Label
        RightMotorPanel          matlab.ui.container.Panel
        RightMotorStateButton    matlab.ui.control.StateButton
        SpeedSlider_2Label       matlab.ui.control.Label
        RightMotorSpeedSlider    matlab.ui.control.Slider
        RightMotorStateLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        s
        m1
        m2
        LeftMarker
        RightMarker
        NoMarker
    end
    
    methods (Access = private)
        
        function toggleMotorState(~,m,button)
            value = button.Value;
            if value
                button.Text = 'Running';
                start(m)
            else
                button.Text = 'Stopped';
                stop(m)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, s, m1, m2, LeftMarker, RightMarker)
            app.s = s;
            app.m1 = m1; %Left Motor
            app.m2 = m2; %Right Motor
            app.LeftMarker = LeftMarker;
            app.RightMarker = RightMarker;
            app.NoMarker = mean([app.LeftMarker app.RightMarker]);
            writePosition(app.s,app.NoMarker)
        end

        % Selection changed function: MarkerSelectButtonGroup
        function MarkerSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.MarkerSelectButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Left'
                    writePosition(app.s,app.LeftMarker)
                case 'None'
                    writePosition(app.s,app.NoMarker)
                case 'Right'
                    writePosition(app.s,app.RightMarker)
            end
        end

        % Value changed function: LeftMotorStateButton
        function LeftMotorStateButtonValueChanged(app, event)
            toggleMotorState(app,app.m1,app.LeftMotorStateButton)
        end

        % Value changed function: LeftMotorSpeedSlider
        function LeftMotorSpeedSliderValueChanged(app, event)
            app.m1.Speed = app.LeftMotorSpeedSlider.Value;
        end

        % Value changed function: RightMotorStateButton
        function RightMotorStateButtonValueChanged(app, event)
            toggleMotorState(app,app.m2,app.RightMotorStateButton)
        end

        % Value changed function: RightMotorSpeedSlider
        function RightMotorSpeedSliderValueChanged(app, event)
            app.m2.Speed = app.RightMotorSpeedSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MarkerSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.MarkerSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MarkerSelectButtonGroupSelectionChanged, true);
            app.MarkerSelectButtonGroup.Title = 'Marker Select';
            app.LeftButton = uitogglebutton(app.MarkerSelectButtonGroup);
            app.LeftButton.Text = 'Left';
            app.NoneButton = uitogglebutton(app.MarkerSelectButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.RightButton = uitogglebutton(app.MarkerSelectButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftMotorPanel = uipanel(app.UIFigure);
            app.LeftMotorPanel.Title = 'Left Motor';
            app.LeftMotorStateButton = uibutton(app.LeftMotorPanel, 'state');
            app.LeftMotorStateButton.ValueChangedFcn = createCallbackFcn(app, @LeftMotorStateButtonValueChanged, true);
            app.LeftMotorStateButton.Text = 'Stopped';
            app.SpeedSliderLabel = uilabel(app.LeftMotorPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.LeftMotorSpeedSlider = uislider(app.LeftMotorPanel);
            app.LeftMotorSpeedSlider.Limits = [-1 1];
            app.LeftMotorSpeedSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.LeftMotorSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @LeftMotorSpeedSliderValueChanged, true);
            app.LeftMotorStateLabel = uilabel(app.LeftMotorPanel);
            app.LeftMotorStateLabel.Text = 'State';
            app.RightMotorPanel = uipanel(app.UIFigure);
            app.RightMotorPanel.Title = 'Right Motor';
            app.RightMotorStateButton = uibutton(app.RightMotorPanel, 'state');
            app.RightMotorStateButton.ValueChangedFcn = createCallbackFcn(app, @RightMotorStateButtonValueChanged, true);
            app.RightMotorStateButton.Text = 'Stopped';
            app.SpeedSlider_2Label = uilabel(app.RightMotorPanel);
            app.SpeedSlider_2Label.Text = 'Speed';
            app.RightMotorSpeedSlider = uislider(app.RightMotorPanel);
            app.RightMotorSpeedSlider.Limits = [-1 1];
            app.RightMotorSpeedSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.RightMotorSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @RightMotorSpeedSliderValueChanged, true);
            app.RightMotorStateLabel = uilabel(app.RightMotorPanel);
            app.RightMotorStateLabel.Text = 'State';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimplePlotterApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end